classdef findRoots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        methodsDropDownLabel         matlab.ui.control.Label
        methodsDropDown              matlab.ui.control.DropDown
        EquationEditFieldLabel       matlab.ui.control.Label
        EquationEditField            matlab.ui.control.EditField
        initialpoint1EditFieldLabel  matlab.ui.control.Label
        initialpoint1EditField       matlab.ui.control.NumericEditField
        initialpoint2EditFieldLabel  matlab.ui.control.Label
        initialpoint2EditField       matlab.ui.control.NumericEditField
        percisionEditFieldLabel      matlab.ui.control.Label
        percisionEditField           matlab.ui.control.NumericEditField
        maxitirationsEditFieldLabel  matlab.ui.control.Label
        maxitirationsEditField       matlab.ui.control.NumericEditField
        fastButton                   matlab.ui.control.Button
        nextButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        Label                        matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
    end


    properties (Access = public)
        methodName % Description
    end


    methods (Access = private)

        % Value changed function: methodsDropDown
        function methodsDropDownValueChanged(app, event)
            value = app.methodsDropDown.Value;
            app.methodName = value;
            %app.TextArea.Value =this.methodName;
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            %str2 = app.EquationEditField.Value;
            %str2 = bisection(0,5, str2,app.UIAxes);
            %Secant(0,5,str2);
            %app.TextArea.Value = str2;
            str = app.EquationEditField.Value;
            x1 = app.initialpoint1EditField.Value;
            x2 = app.initialpoint2EditField.Value;
            maxItr = app.maxitirationsEditField.Value;
            percision = app.percisionEditField.Value;
            
            equation = functionFromString(str);
            
            outputString = "";
            n = app.methodsDropDown.Value;
            switch n
                case "Bisection"
                    %str2 = bisection(0,3, str2,app.UIAxes);
                    [num] =  myBisectionAlgorithm(equation,x1,x2);
                    outputString = num2str(num);
                    
                    
                case "Newton-Raphson"
                    outputString = outputString + Newton_Raphson(x1,equation);
                case "Fixed point"
                    outputString = FixedPoint(x1,equation);
                case "Secant"
                    [num] = Secant(x1,x2,equation);
                    outputString = num2str(num);
                case "False-position"
                    outputString = myFalsePosition(equation,x1,x2);
                case "Bierge Vieta"
                    coefArr = getCoef(str);
                    outputString = birgeVietaCaller(coefArr,x1);
                case "AllRoots"
                
                    
                   % disp('positive one')
                otherwise
                    %disp('other value')
            end
            
            %str2 = bisection(0,3, str2,app.UIAxes);           
            app.TextArea.Value = outputString;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.methodsDropDownLabel = uilabel(app.UIFigure);
            app.methodsDropDownLabel.Text = 'methods';
            app.methodsDropDown = uidropdown(app.UIFigure);
            app.methodsDropDown.Items = {'Bisection', 'False-position', 'Fixed point', 'Newton-Raphson', 'Secant', 'Bierge Vieta', 'AllRoots'};
            app.methodsDropDown.ValueChangedFcn = createCallbackFcn(app, @methodsDropDownValueChanged, true);
            app.methodsDropDown.Value = 'Bisection';
            app.EquationEditFieldLabel = uilabel(app.UIFigure);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.UIFigure, 'text');
            app.initialpoint1EditFieldLabel = uilabel(app.UIFigure);
            app.initialpoint1EditFieldLabel.Text = 'initial point 1';
            app.initialpoint1EditField = uieditfield(app.UIFigure, 'numeric');
            app.initialpoint2EditFieldLabel = uilabel(app.UIFigure);
            app.initialpoint2EditFieldLabel.Text = 'initial point 2';
            app.initialpoint2EditField = uieditfield(app.UIFigure, 'numeric');
            app.percisionEditFieldLabel = uilabel(app.UIFigure);
            app.percisionEditFieldLabel.Text = 'percision';
            app.percisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.percisionEditField.Value = 0.0001;
            app.maxitirationsEditFieldLabel = uilabel(app.UIFigure);
            app.maxitirationsEditFieldLabel.Text = 'max itirations';
            app.maxitirationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxitirationsEditField.Value = 50;
            app.fastButton = uibutton(app.UIFigure, 'push');
            app.fastButton.Text = 'fast';
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = findRoots

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end